classdef Mech466SadikUzan1864271v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MECH466BrakingDynamicsSADIKUZANUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        VehicleSpecificationsTab        matlab.ui.container.Tab
        LoadedPanel                     matlab.ui.container.Panel
        r                               matlab.ui.control.NumericEditField
        distancefromlateralaxisEditFieldLabel  matlab.ui.control.Label
        a                               matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        h                               matlab.ui.control.NumericEditField
        WheelbaseEditFieldLabel         matlab.ui.control.Label
        L                               matlab.ui.control.NumericEditField
        LoadDistributionfrLabel         matlab.ui.control.Label
        f                               matlab.ui.control.NumericEditField
        VehicleMassEditFieldLabel       matlab.ui.control.Label
        Wv                              matlab.ui.control.NumericEditField
        UnloadedPanel                   matlab.ui.container.Panel
        TireRollingRadiusmmEditFieldLabel  matlab.ui.control.Label
        Rw                              matlab.ui.control.NumericEditField
        BrakeWheelCylinderDiameterfrmmEditFieldLabel  matlab.ui.control.Label
        BWCf                            matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        BWCr                            matlab.ui.control.NumericEditField
        BrakefactorfrontdiskrearleadingEditFieldLabel  matlab.ui.control.Label
        BFf                             matlab.ui.control.NumericEditField
        BFr                             matlab.ui.control.NumericEditField
        Rr                              matlab.ui.control.NumericEditField
        EffectiveRadiusfrontdiskreardrummmEditFieldLabel  matlab.ui.control.Label
        Rf                              matlab.ui.control.NumericEditField
        IdealBFDFTab                    matlab.ui.container.Tab
        DecelerationtoFrontLockedFirstLabel  matlab.ui.control.Label
        d1                              matlab.ui.control.NumericEditField
        i_idealEditFieldLabel           matlab.ui.control.Label
        ifora                           matlab.ui.control.NumericEditField
        btnCalca                        matlab.ui.control.Button
        MaxdecelerationwithoutlockingforrearandfirstPanel  matlab.ui.container.Panel
        ForthefronwheelEditFieldLabel   matlab.ui.control.Label
        dforfront                       matlab.ui.control.NumericEditField
        FortheRearWheelEditFieldLabel   matlab.ui.control.Label
        dforrear                        matlab.ui.control.NumericEditField
        AdhesionCoeeficientEditFieldLabel  matlab.ui.control.Label
        MuFor3                          matlab.ui.control.NumericEditField
        MaxTotalBrakingForcewithoutLockbothWheelPanel  matlab.ui.container.Panel
        BrakingForceNEditFieldLabel     matlab.ui.control.Label
        MaxFb3                          matlab.ui.control.NumericEditField
        calcMaxFb3                      matlab.ui.control.Button
        Warn                            matlab.ui.control.Label
        CheckBFDFavailibityPanel        matlab.ui.container.Panel
        Decelerationms2Label            matlab.ui.control.Label
        dforcheck                       matlab.ui.control.NumericEditField
        CheckButton                     matlab.ui.control.Button
        FortheFrontWheelEditFieldLabel  matlab.ui.control.Label
        iforfront                       matlab.ui.control.NumericEditField
        FortheRearWheelEditFieldLabel_2  matlab.ui.control.Label
        iforrear                        matlab.ui.control.NumericEditField
        porNot                          matlab.ui.control.Label
        ActualBDFDTab                   matlab.ui.container.Tab
        DynamicfrontandrearloadsPanel   matlab.ui.container.Panel
        decelerationEditFieldLabel      matlab.ui.control.Label
        dfor4                           matlab.ui.control.NumericEditField
        W_fNEditFieldLabel              matlab.ui.control.Label
        Wf                              matlab.ui.control.NumericEditField
        W_rNEditFieldLabel              matlab.ui.control.Label
        Wr                              matlab.ui.control.NumericEditField
        I_idealEditFieldLabel           matlab.ui.control.Label
        BFDFidealfor4                   matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        ActualBrakeForceDistributionFactorPanel  matlab.ui.container.Panel
        CalculateBFDFactualandCheckforStandartButton  matlab.ui.control.Button
        i_actualEditFieldLabel          matlab.ui.control.Label
        BFDFactual4                     matlab.ui.control.NumericEditField
        ActualandIdealBFDFEqualEditFieldLabel  matlab.ui.control.Label
        dequalat                        matlab.ui.control.NumericEditField
        Warn1                           matlab.ui.control.Label
        d_maxvsiTab                     matlab.ui.container.Tab
        PlotLabel                       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        wheretheroadadhesioncoefficientisEditFieldLabel  matlab.ui.control.Label
        mu                              matlab.ui.control.NumericEditField
        DvsIFigure                      matlab.ui.control.UIAxes
        PlotButton                      matlab.ui.control.Button
    end


    properties (Access = private)
        BFDFideal % Description
        BFDFactual % Description
        d % Description
    end


    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
            app.d = [0.1,0.2,0.3,0.4,0.5,0.6,0.7,0.8,0.9];
            IdealDist = (1-app.a.Value/app.L.Value)+(app.h.Value/app.L.Value)*app.d;
            plot(app.DvsIFigure,IdealDist,1-IdealDist);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            i = 0.2:0.01:1;
            l = app.L.Value; % wheelbase [m]
            a_yuksuz = app.a_u.Value;
            a_yuklu = app.a.Value;
            b_yuksuz = l-a_yuksuz;
            b_yuklu = l-a_yuklu;
            mus = app.mu.Value;
            h_yuksuz = app.h_u.Value;
            h_yuklu=app.h.Value;
            df_yuksuz = mus*b_yuksuz/l./(i-mus*h_yuksuz/l);
            df_yuklu = mus*b_yuklu/l./(i-mus*h_yuklu/l);
            dr_yuksuz = mus*a_yuksuz/l./(1-i+mus*h_yuksuz/l);
            dr_yuklu = mus*a_yuklu/l./(1-i+mus*h_yuklu/l);
            plot(app.DvsIFigure,i*100,[df_yuksuz;df_yuklu;dr_yuksuz;dr_yuklu],'LineWidth',2);
            legend(app.DvsIFigure,'d_f unloaded','d_f loaded','d_r unloaded','d_r loaded');
           % if(app.mu.Value > 0.4)              
             %   ylim(app.DvsIFigure,[0.5 1])
               % xlim(app.DvsIFigure,[30 100])
            %else
            %ylim(app.DvsIFigure,[0.12 0.22])
           % xlim(app.DvsIFigure,[15 95])
           % end
            xlabel(app.DvsIFigure,'brake force distribution factor')
            ylabel(app.DvsIFigure,'max decel before front - rear wheel lock [g]')
   
        end

        % Button pushed function: btnCalca
        function btnCalcaButtonPushed(app, event)
            app.ifora.Value = (1-app.a.Value/app.L.Value) + app.h.Value/app.L.Value*app.d1.Value;
            muhdivL = app.MuFor3.Value*(app.h.Value/app.L.Value);
            app.dforfront.Value = ((app.MuFor3.Value)*(1-app.a.Value/app.L.Value))/(app.ifora.Value-muhdivL);
            app.dforrear.Value = ((app.MuFor3.Value)*(app.a.Value/app.L.Value))/((1-app.ifora.Value) + muhdivL);
        end

        % Button pushed function: calcMaxFb3
        function calcMaxFb3ButtonPushed(app, event)
            if app.dforfront.Value < app.dforrear.Value
                maxd = app.dforfront.Value;
                app.MaxFb3.Value = app.Wv.Value*9.81*maxd;
                app.Warn.Text = "Front Wheel Lock First";
            else
                maxd = app.dforrear.Value;
                app.MaxFb3.Value = app.Wv.Value*9.81*maxd;
                app.Warn.Text = "Rear Wheel Lock First";
            end
            
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            dec = app.dforcheck.Value/9.81;
            Muu = app.MuFor3.Value; 
            app.iforfront.Value = Muu*(1-app.a.Value/app.L.Value + dec*app.h.Value/app.L.Value)/dec;
            app.iforrear.Value = 1-Muu*(app.a.Value/app.L.Value - dec*app.h.Value/app.L.Value)/dec;
            if app.iforfront.Value <= app.iforrear.Value
                app.porNot.Text =  "NOT POSSIBLE";
            else
                app.porNot.Text = "POSSIBLE";
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.Wf.Value = app.Wv.Value*9.81*(app.f.Value/100 + app.dfor4.Value*app.h.Value/app.L.Value);
            app.Wr.Value = app.Wv.Value*9.81*(app.r.Value/100 -app.dfor4.Value*app.h.Value/app.L.Value);
            app.BFDFidealfor4.Value = app.Wf.Value/(app.Wv.Value*9.81);
        end

        % Button pushed function: 
        % CalculateBFDFactualandCheckforStandartButton
        function CalculateBFDFactualandCheckforStandartButtonPushed(app, event)
            numf = pi*(app.BWCf.Value^2/4)*app.BFf.Value*app.Rf.Value;
            denr= numf + pi*(app.BWCr.Value^2/4)*app.BFr.Value*app.Rr.Value;
            app.BFDFactual4.Value = numf/denr;
            
            decEqual = (app.BFDFactual4.Value-app.f.Value/100)*app.L.Value/app.h.Value;
            app.dequalat.Value = round(decEqual,2);
            if app.dequalat.Value == 0.8
                app.Warn1.Text = "Suitable";
            else
                app.Warn1.Text = "Not Suitable";
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MECH466BrakingDynamicsSADIKUZANUIFigure = uifigure;
            app.MECH466BrakingDynamicsSADIKUZANUIFigure.Name = 'MECH 466 | Braking Dynamics | SADIK UZAN';
            app.TabGroup = uitabgroup(app.MECH466BrakingDynamicsSADIKUZANUIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.VehicleSpecificationsTab = uitab(app.TabGroup);
            app.VehicleSpecificationsTab.Title = 'Vehicle Specifications';
            app.LoadedPanel = uipanel(app.VehicleSpecificationsTab);
            app.LoadedPanel.Title = 'Loaded';
            app.r = uieditfield(app.LoadedPanel, 'numeric');
            app.r.ValueDisplayFormat = '%5.2f';
            app.r.Value = 47;
            app.distancefromlateralaxisEditFieldLabel = uilabel(app.LoadedPanel);
            app.distancefromlateralaxisEditFieldLabel.Text = 'distance from lateral axis';
            app.a = uieditfield(app.LoadedPanel, 'numeric');
            app.a.ValueDisplayFormat = '%.2f';
            app.a.Value = 745.89;
            app.HeightEditFieldLabel = uilabel(app.LoadedPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.h = uieditfield(app.LoadedPanel, 'numeric');
            app.h.Value = 576;
            app.WheelbaseEditFieldLabel = uilabel(app.LoadedPanel);
            app.WheelbaseEditFieldLabel.Text = 'Wheelbase';
            app.L = uieditfield(app.LoadedPanel, 'numeric');
            app.L.Value = 2632;
            app.LoadDistributionfrLabel = uilabel(app.LoadedPanel);
            app.LoadDistributionfrLabel.Text = 'Load Distribution (f/r) %';
            app.f = uieditfield(app.LoadedPanel, 'numeric');
            app.f.ValueDisplayFormat = '%5.2f';
            app.f.Value = 53;
            app.VehicleMassEditFieldLabel = uilabel(app.LoadedPanel);
            app.VehicleMassEditFieldLabel.Text = 'Vehicle Mass';
            app.Wv = uieditfield(app.LoadedPanel, 'numeric');
            app.Wv.Value = 1587;
            app.UnloadedPanel = uipanel(app.VehicleSpecificationsTab);
            app.UnloadedPanel.Title = 'Unloaded';
            app.TireRollingRadiusmmEditFieldLabel = uilabel(app.UnloadedPanel);
            app.TireRollingRadiusmmEditFieldLabel.Text = 'Tire Rolling Radius (mm)';
            app.Rw = uieditfield(app.UnloadedPanel, 'numeric');
            app.Rw.Value = 302;
            app.BrakeWheelCylinderDiameterfrmmEditFieldLabel = uilabel(app.UnloadedPanel);
            app.BrakeWheelCylinderDiameterfrmmEditFieldLabel.Text = 'Brake Wheel Cylinder Diameter (f/r) mm';
            app.BWCf = uieditfield(app.UnloadedPanel, 'numeric');
            app.BWCf.Value = 63.2;
            app.EditFieldLabel = uilabel(app.UnloadedPanel);
            app.EditFieldLabel.Text = '';
            app.BWCr = uieditfield(app.UnloadedPanel, 'numeric');
            app.BWCr.Value = 21.07;
            app.BrakefactorfrontdiskrearleadingEditFieldLabel = uilabel(app.UnloadedPanel);
            app.BrakefactorfrontdiskrearleadingEditFieldLabel.Text = 'Brake factor (front disk / rear leading )';
            app.BFf = uieditfield(app.UnloadedPanel, 'numeric');
            app.BFf.Value = 0.88;
            app.BFr = uieditfield(app.UnloadedPanel, 'numeric');
            app.BFr.Value = 2.8;
            app.Rr = uieditfield(app.UnloadedPanel, 'numeric');
            app.Rr.Value = 102.4;
            app.EffectiveRadiusfrontdiskreardrummmEditFieldLabel = uilabel(app.VehicleSpecificationsTab);
            app.EffectiveRadiusfrontdiskreardrummmEditFieldLabel.Text = 'Effective Radius (front disk / rear drum) mm';
            app.Rf = uieditfield(app.VehicleSpecificationsTab, 'numeric');
            app.Rf.Value = 83;
            app.IdealBFDFTab = uitab(app.TabGroup);
            app.IdealBFDFTab.Title = 'Ideal BFDF';
            app.DecelerationtoFrontLockedFirstLabel = uilabel(app.IdealBFDFTab);
            app.DecelerationtoFrontLockedFirstLabel.Text = 'Deceleration to Front Locked First';
            app.d1 = uieditfield(app.IdealBFDFTab, 'numeric');
            app.d1.ValueDisplayFormat = '%.3f';
            app.i_idealEditFieldLabel = uilabel(app.IdealBFDFTab);
            app.i_idealEditFieldLabel.Text = 'i_ideal';
            app.ifora = uieditfield(app.IdealBFDFTab, 'numeric');
            app.ifora.ValueDisplayFormat = '%.3f';
            app.btnCalca = uibutton(app.IdealBFDFTab, 'push');
            app.btnCalca.ButtonPushedFcn = createCallbackFcn(app, @btnCalcaButtonPushed, true);
            app.btnCalca.Text = 'Calculate';
            app.MaxdecelerationwithoutlockingforrearandfirstPanel = uipanel(app.IdealBFDFTab);
            app.MaxdecelerationwithoutlockingforrearandfirstPanel.Title = 'Max deceleration without locking for rear and first';
            app.ForthefronwheelEditFieldLabel = uilabel(app.MaxdecelerationwithoutlockingforrearandfirstPanel);
            app.ForthefronwheelEditFieldLabel.Text = 'For the fron wheel';
            app.dforfront = uieditfield(app.MaxdecelerationwithoutlockingforrearandfirstPanel, 'numeric');
            app.dforfront.ValueDisplayFormat = '%.3f';
            app.FortheRearWheelEditFieldLabel = uilabel(app.MaxdecelerationwithoutlockingforrearandfirstPanel);
            app.FortheRearWheelEditFieldLabel.Text = 'For the Rear Wheel';
            app.dforrear = uieditfield(app.MaxdecelerationwithoutlockingforrearandfirstPanel, 'numeric');
            app.dforrear.ValueDisplayFormat = '%.3f';
            app.AdhesionCoeeficientEditFieldLabel = uilabel(app.IdealBFDFTab);
            app.AdhesionCoeeficientEditFieldLabel.Text = 'Adhesion Coeeficient';
            app.MuFor3 = uieditfield(app.IdealBFDFTab, 'numeric');
            app.MuFor3.ValueDisplayFormat = '%.3f';
            app.MaxTotalBrakingForcewithoutLockbothWheelPanel = uipanel(app.IdealBFDFTab);
            app.MaxTotalBrakingForcewithoutLockbothWheelPanel.Title = 'Max Total Braking Force without Lock both Wheel';
            app.BrakingForceNEditFieldLabel = uilabel(app.MaxTotalBrakingForcewithoutLockbothWheelPanel);
            app.BrakingForceNEditFieldLabel.Text = 'Braking Force (N)';
            app.MaxFb3 = uieditfield(app.MaxTotalBrakingForcewithoutLockbothWheelPanel, 'numeric');
            app.calcMaxFb3 = uibutton(app.MaxTotalBrakingForcewithoutLockbothWheelPanel, 'push');
            app.calcMaxFb3.ButtonPushedFcn = createCallbackFcn(app, @calcMaxFb3ButtonPushed, true);
            app.calcMaxFb3.Text = 'Find Force';
            app.Warn = uilabel(app.MaxTotalBrakingForcewithoutLockbothWheelPanel);
            app.Warn.Text = '';
            app.CheckBFDFavailibityPanel = uipanel(app.IdealBFDFTab);
            app.CheckBFDFavailibityPanel.Title = 'Check BFDF availibity';
            app.Decelerationms2Label = uilabel(app.CheckBFDFavailibityPanel);
            app.Decelerationms2Label.Text = 'Deceleration (m/s^2)';
            app.dforcheck = uieditfield(app.CheckBFDFavailibityPanel, 'numeric');
            app.dforcheck.ValueDisplayFormat = '%.3f';
            app.CheckButton = uibutton(app.CheckBFDFavailibityPanel, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.FortheFrontWheelEditFieldLabel = uilabel(app.CheckBFDFavailibityPanel);
            app.FortheFrontWheelEditFieldLabel.Text = 'For the Front Wheel';
            app.iforfront = uieditfield(app.CheckBFDFavailibityPanel, 'numeric');
            app.iforfront.ValueDisplayFormat = '%.3f';
            app.FortheRearWheelEditFieldLabel_2 = uilabel(app.CheckBFDFavailibityPanel);
            app.FortheRearWheelEditFieldLabel_2.Text = 'For the Rear Wheel';
            app.iforrear = uieditfield(app.CheckBFDFavailibityPanel, 'numeric');
            app.iforrear.ValueDisplayFormat = '%.3f';
            app.porNot = uilabel(app.CheckBFDFavailibityPanel);
            app.porNot.Text = '';
            app.ActualBDFDTab = uitab(app.TabGroup);
            app.ActualBDFDTab.Title = 'Actual BDFD';
            app.DynamicfrontandrearloadsPanel = uipanel(app.ActualBDFDTab);
            app.DynamicfrontandrearloadsPanel.Title = 'Dynamic front and rear loads';
            app.decelerationEditFieldLabel = uilabel(app.DynamicfrontandrearloadsPanel);
            app.decelerationEditFieldLabel.Text = '@ deceleration ';
            app.dfor4 = uieditfield(app.DynamicfrontandrearloadsPanel, 'numeric');
            app.W_fNEditFieldLabel = uilabel(app.DynamicfrontandrearloadsPanel);
            app.W_fNEditFieldLabel.Text = 'W_f [N]';
            app.Wf = uieditfield(app.DynamicfrontandrearloadsPanel, 'numeric');
            app.W_rNEditFieldLabel = uilabel(app.DynamicfrontandrearloadsPanel);
            app.W_rNEditFieldLabel.Text = 'W_r [N]';
            app.Wr = uieditfield(app.DynamicfrontandrearloadsPanel, 'numeric');
            app.I_idealEditFieldLabel = uilabel(app.DynamicfrontandrearloadsPanel);
            app.I_idealEditFieldLabel.Text = 'I_ideal';
            app.BFDFidealfor4 = uieditfield(app.DynamicfrontandrearloadsPanel, 'numeric');
            app.CalculateButton = uibutton(app.DynamicfrontandrearloadsPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ActualBrakeForceDistributionFactorPanel = uipanel(app.ActualBDFDTab);
            app.ActualBrakeForceDistributionFactorPanel.Title = 'Actual Brake Force Distribution Factor';
            app.CalculateBFDFactualandCheckforStandartButton = uibutton(app.ActualBrakeForceDistributionFactorPanel, 'push');
            app.CalculateBFDFactualandCheckforStandartButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateBFDFactualandCheckforStandartButtonPushed, true);
            app.CalculateBFDFactualandCheckforStandartButton.Text = 'Calculate BFDF (actual) and Check for Standart';
            app.i_actualEditFieldLabel = uilabel(app.ActualBrakeForceDistributionFactorPanel);
            app.i_actualEditFieldLabel.Text = 'i_actual';
            app.BFDFactual4 = uieditfield(app.ActualBrakeForceDistributionFactorPanel, 'numeric');
            app.ActualandIdealBFDFEqualEditFieldLabel = uilabel(app.ActualBrakeForceDistributionFactorPanel);
            app.ActualandIdealBFDFEqualEditFieldLabel.Text = 'Actual and Ideal BFDF Equal @';
            app.dequalat = uieditfield(app.ActualBrakeForceDistributionFactorPanel, 'numeric');
            app.Warn1 = uilabel(app.ActualBrakeForceDistributionFactorPanel);
            app.Warn1.Text = 'NOT SUITABLE';
            app.d_maxvsiTab = uitab(app.TabGroup);
            app.d_maxvsiTab.Title = 'd_max vs i';
            app.PlotLabel = uilabel(app.d_maxvsiTab);
            app.PlotLabel.Text = 'Plot the limiting values of deceleration before the front and rear wheels lock versus brake force distribution factor (BFDF)';
            app.Label = uilabel(app.d_maxvsiTab);
            app.Label.Text = ' for both driver only and loaded conditions';
            app.wheretheroadadhesioncoefficientisEditFieldLabel = uilabel(app.d_maxvsiTab);
            app.wheretheroadadhesioncoefficientisEditFieldLabel.Text = ', where the road adhesion coefficient is';
            app.mu = uieditfield(app.d_maxvsiTab, 'numeric');
            app.DvsIFigure = uiaxes(app.d_maxvsiTab);
            xlabel(app.DvsIFigure, 'X')
            ylabel(app.DvsIFigure, 'Y')
            app.DvsIFigure.XLim = [0 1];
            app.DvsIFigure.YLim = [0 1];
            app.DvsIFigure.ZLim = [0 1];
            app.DvsIFigure.CLim = [0 1];
            app.DvsIFigure.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DvsIFigure.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DvsIFigure.ZTick = [0 0.5 1];
            app.PlotButton = uibutton(app.d_maxvsiTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Mech466SadikUzan1864271v1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MECH466BrakingDynamicsSADIKUZANUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MECH466BrakingDynamicsSADIKUZANUIFigure)
        end
    end
end